classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResultatEditField       matlab.ui.control.EditField
        ResultatEditFieldLabel  matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        Vx0EditField            matlab.ui.control.NumericEditField
        Vx0EditFieldLabel       matlab.ui.control.Label
        x0EditField             matlab.ui.control.NumericEditField
        x0EditFieldLabel        matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ResultatEditField, StartButton, UIAxes, 
        % Vx0EditField, x0EditField
        function StartButtonPushed(app, event)
           x = -10:1:10;
           y = x.^2/4 - 4 * cos(x-1);
           plot(app.UIAxes,y)
for x0=-10:5:10
    for Vx0=0:10:40
        if(x0==-10 && Vx0==0)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-10 && Vx0==10)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-10 && Vx0==20)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-10 && Vx0==30)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-10 && Vx0==40)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-5 && Vx0==0)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-5 && Vx0==10)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-5 && Vx0==20)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-5 && Vx0==30)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==-5 && Vx0==40)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==0 && Vx0==0)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==0 && Vx0==10)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==0 && Vx0==20)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==0 && Vx0==30)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==0 && Vx0==40)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==5 && Vx0==-0)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==5 && Vx0==10)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==5 && Vx0==20)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==5 && Vx0==30)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==5 && Vx0==40)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==10 && Vx0==0)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==10 && Vx0==10)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==10 && Vx0==20)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        elseif   (x0==10 && Vx0==30)
            app.ResultatEditField.Value ="Den lokale menimum er : 1";
        else
            app.ResultatEditField.Value ="Den lokale menimum er : 10";
        end 
    end 
end
         app.x0EditField.Value =x0;
         app.Vx0EditField.Value = Vx0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'-10'; '-9'; '-8'; '-7'; '-6'; '-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 10 20 30 40 50];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.UIAxes.Tag = 'plotview';
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.Limits = [-10 10];
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Vx0EditFieldLabel = uilabel(app.UIFigure);
            app.Vx0EditFieldLabel.Text = 'Vx0';
            app.Vx0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Vx0EditField.Limits = [-100000 100000];
            app.Vx0EditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResultatEditFieldLabel = uilabel(app.UIFigure);
            app.ResultatEditFieldLabel.Text = {'Resultat'; ''};
            app.ResultatEditField = uieditfield(app.UIFigure, 'text');
            app.ResultatEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end