classdef App_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Xmax                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel     matlab.ui.control.Label
        Vmax                   matlab.ui.control.NumericEditField
        VmaxEditFieldLabel     matlab.ui.control.Label
        dvx                    matlab.ui.control.NumericEditField
        dvxEditFieldLabel      matlab.ui.control.Label
        simulationspeedLabel   matlab.ui.control.Label
        speed                  matlab.ui.control.Switch
        function_DropDown      matlab.ui.control.DropDown
        functionDropDownLabel  matlab.ui.control.Label
        dx                     matlab.ui.control.NumericEditField
        dxEditFieldLabel       matlab.ui.control.Label
        Image4                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Image                  matlab.ui.control.Image
        ButtonGroup            matlab.ui.container.ButtonGroup
        Button_4               matlab.ui.control.RadioButton
        Button_3               matlab.ui.control.RadioButton
        Button_2               matlab.ui.control.RadioButton
        Button_1               matlab.ui.control.RadioButton
        simulation             matlab.ui.control.Switch
        PlottIkkeplotLabel     matlab.ui.control.Label
        mapButton              matlab.ui.control.Button
        Vx0                    matlab.ui.control.Slider
        Vx0SliderLabel         matlab.ui.control.Label
        X0                     matlab.ui.control.Slider
        X0Label                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        RunButton              matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
          MainFunksjon(app,app.simulation.Value,str2double(app.ButtonGroup.SelectedObject.Text),app.speed.Value);
        end

        % Button pushed function: mapButton
        function mapButtonPushed(app, event)
            map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            switch (str2double(app.ButtonGroup.SelectedObject.Text))
            case 1
              app.function_DropDown.Value="f1";
            case 2
              app.function_DropDown.Value="f2";
            case 3
              app.function_DropDown.Value="f3";
            case 4
              app.function_DropDown.Value="f4";
           end
            MainFunksjon(app,app.simulation.Value,str2double(app.ButtonGroup.SelectedObject.Text),app.speed.Value);
            map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.function_DropDown.Value="f1";
            app.Button_1.Value=true;
            MainFunksjon(app,app.simulation.Value,1,app.speed.Value);
            map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.function_DropDown.Value="f2";
            app.Button_2.Value=true;
            MainFunksjon(app,app.simulation.Value,2,app.speed.Value);
            map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            app.function_DropDown.Value="f3";
            app.Button_3.Value=true;
            MainFunksjon(app,app.simulation.Value,3,app.speed.Value);
            map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            app.function_DropDown.Value="f4";
            app.Button_4.Value=true;
            MainFunksjon(app,app.simulation.Value,4,app.speed.Value);
            map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
        end

        % Value changed function: function_DropDown
        function function_DropDownValueChanged(app, event)
           % value = app.function_DropDown.Value;
           %app.function_DropDown.Position;
           value = app.function_DropDown.Value;
           switch (value)
            case "f1"
              app.Button_1.Value=true;
              MainFunksjon(app,app.simulation.Value,1,app.speed.Value);
              map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
            case "f2"
              app.Button_2.Value=true;
              MainFunksjon(app,app.simulation.Value,2,app.speed.Value);
              map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
            case "f3"
              app.Button_3.Value=true;
              MainFunksjon(app,app.simulation.Value,3,app.speed.Value);
              map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
            case "f4"
              app.Button_4.Value=true;
              MainFunksjon(app,app.simulation.Value,4,app.speed.Value);
              map(app,app.dx.Value,app.dvx.Value,app.Xmax.Value,app.Vmax.Value,str2double(app.ButtonGroup.SelectedObject.Text));
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'simulating sliding')
            xlabel(app.UIAxes, 'X axis')
            ylabel(app.UIAxes, 'Y axis')
            app.UIAxes.Tag = 'dddd';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'map')
            xlabel(app.UIAxes2, 'Vx0')
            ylabel(app.UIAxes2, 'x0')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.X0Label = uilabel(app.UIFigure);
            app.X0Label.Text = 'X0';
            app.X0 = uislider(app.UIFigure);
            app.X0.Limits = [-10 10];
            app.Vx0SliderLabel = uilabel(app.UIFigure);
            app.Vx0SliderLabel.Text = 'Vx0';
            app.Vx0 = uislider(app.UIFigure);
            app.Vx0.Limits = [-30 30];
            app.mapButton = uibutton(app.UIFigure, 'push');
            app.mapButton.ButtonPushedFcn = createCallbackFcn(app, @mapButtonPushed, true);
            app.mapButton.Text = 'map';
            app.PlottIkkeplotLabel = uilabel(app.UIFigure);
            app.PlottIkkeplotLabel.Text = {'Ikke plot/Plot'; ''};
            app.simulation = uiswitch(app.UIFigure, 'slider');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '4';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '1.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = '2.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = '3.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = '4.png';
            app.dxEditFieldLabel = uilabel(app.UIFigure);
            app.dxEditFieldLabel.Text = 'dx';
            app.dx = uieditfield(app.UIFigure, 'numeric');
            app.dx.Value = 1;
            app.functionDropDownLabel = uilabel(app.UIFigure);
            app.functionDropDownLabel.Text = 'function';
            app.function_DropDown = uidropdown(app.UIFigure);
            app.function_DropDown.Items = {'f1', 'f2', 'f3', 'f4'};
            app.function_DropDown.ValueChangedFcn = createCallbackFcn(app, @function_DropDownValueChanged, true);
            app.function_DropDown.Value = 'f1';
            app.speed = uiswitch(app.UIFigure, 'slider');
            app.speed.Items = {'slow', 'fast'};
            app.speed.Value = 'slow';
            app.simulationspeedLabel = uilabel(app.UIFigure);
            app.simulationspeedLabel.Text = 'simulation speed';
            app.dvxEditFieldLabel = uilabel(app.UIFigure);
            app.dvxEditFieldLabel.Text = 'dvx';
            app.dvx = uieditfield(app.UIFigure, 'numeric');
            app.dvx.Value = 1;
            app.VmaxEditFieldLabel = uilabel(app.UIFigure);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.Vmax = uieditfield(app.UIFigure, 'numeric');
            app.Vmax.Value = 10;
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.Xmax = uieditfield(app.UIFigure, 'numeric');
            app.Xmax.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end